classdef DataAnalisys2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        axSignal              matlab.ui.control.UIAxes
        axFft                 matlab.ui.control.UIAxes
        StartSliderLabel      matlab.ui.control.Label
        sldStart              matlab.ui.control.Slider
        ConfigurationPanel    matlab.ui.container.Panel
        btnFile               matlab.ui.control.Button
        SamplesXSpinnerLabel  matlab.ui.control.Label
        spSamples             matlab.ui.control.Spinner
        lblSamples            matlab.ui.control.Label
        DrawA1SwitchLabel     matlab.ui.control.Label
        swA1                  matlab.ui.control.RockerSwitch
    end


    properties (Access = private)
        nSamples % Description
        packets % Description
    end

    methods (Access = private)
    
        
        function results = reloadData(app)
         
            min = int16(app.sldStart.Value);
            max = min+app.nSamples;
            packet = app.packets(1);
            time = packet.data(min:max,1);
            a0=packet.data(min:max,3);
            a1=packet.data(min:max,4);
            if strcmpi(app.swA1.Value,'On')
                plot(app.axSignal,time,a0,time,a1);
            else
                plot(app.axSignal,time,a0);
            end

            Fs = 1000;            % Sampling frequency                    
            L = app.nSamples;             % Length of signal
            Y0 = fft(double(a0));
            Y0= fftshift(Y0);
            P0 = abs(Y0/double(L));
            Y1=fft(double(a1));
            Y1= fftshift(Y1);
            P1 = abs(Y1/double(L));
            
            
            f = (-L/2:L/2-1)*(Fs/L);
            sf = size(f);
            sf=sf(2);
            sp = size(P0);
            sp=sp(1);
            if sf ~= sp
                P0=P0(1:sf);
                P1=P1(1:sf);
            end
            
            
            if strcmpi(app.swA1.Value,'On')
                plot(app.axFft,f,P0,f,P1);
            else
                plot(app.axFft,f,P0);
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.nSamples=20;
        end

        % Value changed function: spSamples
        function spSamplesChanged(app, event)
            value = app.spSamples.Value;
            app.nSamples = value *20;
            app.lblSamples.Text = strcat('Samples Used: ',num2str(app.nSamples));
            limit = 1024-app.nSamples;
            app.sldStart.Limits = [1 limit];
            reloadData(app);
        end

        % Button pushed function: btnFile
        function btnFileClick(app, event)
            [f,p] = uigetfile('data-*.csv');
            if p~=0
                if exist([p,f,'.mat'], 'file') == 2
                    load([p,f,'.mat']);
                else
                    tmp=readDataCsv([p,f]);
                    save([p,f,'.mat'],'tmp');
                end
                app.packets=tmp;
                reloadData(app);
            end
        end

        % Value changed function: sldStart
        function sldStartValueChanged(app, event)
           reloadData(app);
            
        end

        % Value changed function: swA1
        function swA1Changed(app, event)
            reloadData(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.axSignal = uiaxes(app.UIFigure);
            title(app.axSignal, 'Signal')
            xlabel(app.axSignal, 'X')
            ylabel(app.axSignal, 'Y')
            app.axFft = uiaxes(app.UIFigure);
            title(app.axFft, 'Frecuency')
            xlabel(app.axFft, 'X')
            ylabel(app.axFft, 'Y')
            app.StartSliderLabel = uilabel(app.UIFigure);
            app.StartSliderLabel.Text = 'Start';
            app.sldStart = uislider(app.UIFigure);
            app.sldStart.Limits = [1 1004];
            app.sldStart.MajorTickLabels = {'', '', '', '', '', ''};
            app.sldStart.ValueChangedFcn = createCallbackFcn(app, @sldStartValueChanged, true);
            app.sldStart.Value = 1;
            app.ConfigurationPanel = uipanel(app.UIFigure);
            app.ConfigurationPanel.Title = 'Configuration';
            app.btnFile = uibutton(app.ConfigurationPanel, 'push');
            app.btnFile.ButtonPushedFcn = createCallbackFcn(app, @btnFileClick, true);
            app.btnFile.Text = 'Load';
            app.SamplesXSpinnerLabel = uilabel(app.ConfigurationPanel);
            app.SamplesXSpinnerLabel.Text = 'Samples X';
            app.spSamples = uispinner(app.ConfigurationPanel);
            app.spSamples.ValueChangedFcn = createCallbackFcn(app, @spSamplesChanged, true);
            app.spSamples.Limits = [1 50];
            app.spSamples.Value = 1;
            app.lblSamples = uilabel(app.ConfigurationPanel);
            app.lblSamples.Text = 'Samples Used: 20';
            app.DrawA1SwitchLabel = uilabel(app.ConfigurationPanel);
            app.DrawA1SwitchLabel.Text = 'Draw A1';
            app.swA1 = uiswitch(app.ConfigurationPanel, 'rocker');
            app.swA1.ValueChangedFcn = createCallbackFcn(app, @swA1Changed, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = DataAnalisys2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end