classdef DataVisualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataVisualizerUIFigure  matlab.ui.Figure
        axSignal                matlab.ui.control.UIAxes
        axData                  matlab.ui.control.UIAxes
        ConfigurationPanel      matlab.ui.container.Panel
        btnFile                 matlab.ui.control.Button
        spSamplesLabel          matlab.ui.control.Label
        spSamples               matlab.ui.control.Spinner
        lblSamples              matlab.ui.control.Label
        btnAdd                  matlab.ui.control.Button
        btnLess                 matlab.ui.control.Button
        selVis                  matlab.ui.control.DropDown
        btnFigure               matlab.ui.control.Button
        SelectorPanel           matlab.ui.container.Panel
        opt0                    matlab.ui.control.StateButton
        opt50                   matlab.ui.control.StateButton
        opt150                  matlab.ui.control.StateButton
        opt250                  matlab.ui.control.StateButton
        opt350                  matlab.ui.control.StateButton
    end


    properties (Access = private)
        packets % Description
        nSamples % Description
        packetIndex % Description
    end

    methods (Access = private)
    
        function reloadData(app)
            paintData(app,app.axSignal,app.axData);
        end
        
        function paintData(app, axPaintSignal, axPaintData)
                        l=size(app.packets);
            if app.packetIndex>= l(2)
                app.btnAdd.Enable = 'off';
                app.packetIndex=l(2);
            else
                app.btnAdd.Enable = 'on';
            end
            
            if app.packetIndex<= 1 
                app.btnLess.Enable = 'off';
                app.packetIndex=1;
            else
                app.btnLess.Enable = 'on';
            end
            
            
            packet = app.packets(app.packetIndex);
            a0 = packet.data(:,3);
            plot(axPaintSignal,a0);
            
            modeStr = app.selVis.Value;
            if strcmp('Error per Samples',modeStr)
                title(axPaintData, 'Max Error');
                %xlabel(axPaintData, 'Window Size');
                %ylabel(axPaintData, 'Error');
                set( get(axPaintData,'XLabel'), 'String', 'Window Size' );
                set( get(axPaintData,'YLabel'), 'String', 'Error' );
             
                data = analisysSizeSamplesError(packet);
                app.spSamples.Enable = 'off';
            elseif strcmp('Average per Samples',modeStr)
                title(axPaintData, 'Average Value');
                %xlabel(axPaintData, 'Window Size');
                %ylabel(axPaintData, 'Value');
                set( get(axPaintData,'XLabel'), 'String', 'Window Size' );
                set( get(axPaintData,'YLabel'), 'String', 'Value' );
                
                data = analisysSizeSamplesError(packet,'average');
                app.spSamples.Enable = 'off';
            elseif strcmp('Data Extracted',modeStr)
                title(axPaintData, 'Data ');
                %xlabel(axPaintData, 'Window Size');
                %ylabel(axPaintData, 'Value');
                set( get(axPaintData,'XLabel'), 'String', 'Window Size' );
                set( get(axPaintData,'YLabel'), 'String', 'Value' );
                data = calcSize(packet,app.spSamples.Value);
                app.spSamples.Enable = 'on';
            end
            
            index = [];
            if app.opt0.Value
                index=[1];
            end
            if app.opt50.Value
                index=[index, 2];
            end
            if app.opt150.Value
                index=[index, 3];
            end
            if app.opt250.Value
                index=[index, 4];
            end
            if app.opt350.Value
                index=[index, 5];
            end
            color=[      0    0.4470    0.7410; 0.8500    0.3250    0.0980;   0.9290    0.6940    0.1250;  0.4940    0.1840    0.5560;
                    0.4660    0.6740    0.1880;  0.3010    0.7450    0.9330;    0.6350    0.0780    0.1840];
            color =  color(index,:);
            legend(axPaintData,'off');
            ax=plot(axPaintData,data(:,index));
            for i=1:size(ax)
                set(ax(i),'Color',color(i,:));
                switch index(i)
                    case 1
                        set(ax(i),'DisplayName','0 Hz');
                    case 2
                        set(ax(i),'DisplayName','50 Hz');
                    case 3
                        set(ax(i),'DisplayName','150 Hz');
                    case 4
                        set(ax(i),'DisplayName','250 Hz');
                    case 5
                        set(ax(i),'DisplayName','350 Hz');
                end
            end
            
            legend(axPaintData,'show');
        end
        
    end


    methods (Access = private)

        % Button pushed function: btnAdd
        function btnAddClick(app, event)
            app.packetIndex = app.packetIndex +1;
            reloadData(app);
        end

        % Button pushed function: btnFigure
        function btnFigureClick(app, event)
            figure;
            axFigSignal = subplot(5,1,1);
            axFigData = subplot(5,1,[2:5]);
            paintData(app,axFigSignal,axFigData);
        end

        % Button pushed function: btnFile
        function btnFileClick(app, event)
            [f,p] = uigetfile('data-*.csv');
            if p~=0
                if exist([p,f,'.mat'], 'file') == 2
                    load([p,f,'.mat']);
                else
                    tmp=readDataCsv([p,f]);
                    save([p,f,'.mat'],'tmp');
                end
                app.packets=tmp;
                
                app.packetIndex=1;
                app.selVis.Enable='on';
                app.opt0.Enable='on';
                app.opt50.Enable='on';
                app.opt150.Enable='on';
                app.opt250.Enable='on';
                app.opt350.Enable='on';
                app.btnFigure.Enable='on';
                reloadData(app);
            
            end
            
        end

        % Button pushed function: btnLess
        function btnLessClick(app, event)
            app.packetIndex = app.packetIndex -1;
            reloadData(app);
        end

        % Value changed function: opt0, opt150, opt250, opt350, opt50
        function optChanged(app, event)
            reloadData(app); 
        end

        % Value changed function: selVis
        function selVisChangend(app, event)
            reloadData(app);            
        end

        % Value changed function: spSamples
        function spSamplesChanged(app, event)
            value = app.spSamples.Value;
            app.nSamples = value *20;
            app.lblSamples.Text = strcat('Samples Used: ',num2str(app.nSamples));
            reloadData(app);        
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataVisualizerUIFigure = uifigure;
            app.DataVisualizerUIFigure.Name = 'Data Visualizer';
            app.axSignal = uiaxes(app.DataVisualizerUIFigure);
            app.axData = uiaxes(app.DataVisualizerUIFigure);
            title(app.axData, 'Max Error')
            xlabel(app.axData, 'Start Sample')
            ylabel(app.axData, 'Error')
            app.ConfigurationPanel = uipanel(app.DataVisualizerUIFigure);
            app.ConfigurationPanel.Title = 'Configuration';
            app.btnFile = uibutton(app.ConfigurationPanel, 'push');
            app.btnFile.ButtonPushedFcn = createCallbackFcn(app, @btnFileClick, true);
            app.btnFile.Text = 'Load';
            app.spSamplesLabel = uilabel(app.ConfigurationPanel);
            app.spSamplesLabel.Enable = 'off';
            app.spSamplesLabel.Text = 'Samples X';
            app.spSamples = uispinner(app.ConfigurationPanel);
            app.spSamples.ValueChangedFcn = createCallbackFcn(app, @spSamplesChanged, true);
            app.spSamples.Limits = [1 50];
            app.spSamples.Enable = 'off';
            app.spSamples.Value = 15;
            app.lblSamples = uilabel(app.ConfigurationPanel);
            app.lblSamples.Text = 'Samples Used:300';
            app.btnAdd = uibutton(app.ConfigurationPanel, 'push');
            app.btnAdd.ButtonPushedFcn = createCallbackFcn(app, @btnAddClick, true);
            app.btnAdd.Enable = 'off';
            app.btnAdd.Text = '>';
            app.btnLess = uibutton(app.ConfigurationPanel, 'push');
            app.btnLess.ButtonPushedFcn = createCallbackFcn(app, @btnLessClick, true);
            app.btnLess.Enable = 'off';
            app.btnLess.Text = '<';
            app.selVis = uidropdown(app.ConfigurationPanel);
            app.selVis.Items = {'Error per Samples', 'Data Extracted', 'Average per Samples'};
            app.selVis.ValueChangedFcn = createCallbackFcn(app, @selVisChangend, true);
            app.selVis.Enable = 'off';
            app.selVis.Value = 'Error per Samples';
            app.btnFigure = uibutton(app.ConfigurationPanel, 'push');
            app.btnFigure.ButtonPushedFcn = createCallbackFcn(app, @btnFigureClick, true);
            app.btnFigure.Enable = 'off';
            app.btnFigure.Text = 'Figure';
            app.SelectorPanel = uipanel(app.DataVisualizerUIFigure);
            app.SelectorPanel.Title = 'Selector';
            app.opt0 = uibutton(app.SelectorPanel, 'state');
            app.opt0.ValueChangedFcn = createCallbackFcn(app, @optChanged, true);
            app.opt0.Enable = 'off';
            app.opt0.Text = 'F 0 Hz';
            app.opt0.Value = true;
            app.opt50 = uibutton(app.SelectorPanel, 'state');
            app.opt50.ValueChangedFcn = createCallbackFcn(app, @optChanged, true);
            app.opt50.Enable = 'off';
            app.opt50.Text = 'F 50 Hz';
            app.opt50.Value = true;
            app.opt150 = uibutton(app.SelectorPanel, 'state');
            app.opt150.ValueChangedFcn = createCallbackFcn(app, @optChanged, true);
            app.opt150.Enable = 'off';
            app.opt150.Text = 'F 150 Hz';
            app.opt150.Value = true;
            app.opt250 = uibutton(app.SelectorPanel, 'state');
            app.opt250.ValueChangedFcn = createCallbackFcn(app, @optChanged, true);
            app.opt250.Enable = 'off';
            app.opt250.Text = 'F 250 Hz';
            app.opt250.Value = true;
            app.opt350 = uibutton(app.SelectorPanel, 'state');
            app.opt350.ValueChangedFcn = createCallbackFcn(app, @optChanged, true);
            app.opt350.Enable = 'off';
            app.opt350.Text = 'F 350 Hz';
            app.opt350.Value = true;
        end
    end

    methods (Access = public)

        % Construct app
        function app = DataVisualizer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataVisualizerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataVisualizerUIFigure)
        end
    end
end